classdef task1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SavePolygonButton  matlab.ui.control.Button
        DrawPolygonButton  matlab.ui.control.Button
        LoadPolygonButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        roi % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawPolygonButton
        function drawPolygon(app, event)
            % This code is used for TASK 1.
            % Allows the user to draw a shape on the Axes
            
            app.roi = drawpolygon(app.UIAxes);
        end

        % Button pushed function: SavePolygonButton
        function savePolygon(app, event)
            % This code is used for TASK 1.
            % In order to use save button the user must draw something
            % on the axes before saving.
            % Save button will save all the coordinates of drawn polygon to
            % space separated file.
            
            coordinates = app.roi.Position; % Gets coordinates from drawn polygon
            name = input ('What would you like to call the file? e.g "Test123" > ', 's');
            dlmwrite([name,'.txt'],coordinates,'delimiter',' '); % Writes coordinates to a txt file
        end

        % Button pushed function: LoadPolygonButton
        function loadPolygon(app, event)
            % This code is used for TASK 1.
            cla(app.UIAxes,'reset')
            % The user may only use {.dat and .txt} files to load data from.
            
            [filename, ~] = uigetfile({'*.dat;*.txt'}, 'Pick a file to load data from'); 
            if filename ~= 0
                % Checks the file extension on selected file
                [~, ~, fExt] = fileparts(filename);
                  switch lower(fExt)
                      case '.txt'
                        Temp = importdata(filename, ' ');
                      case '.dat'
                        Temp = importdata(filename);
                      otherwise 
                        error('Unexpected file extension: %s', fExt);
                  end
            end
            % Gets X and Y coordinates from Temp variable.
            X = Temp(:,1);
            Y = Temp(:,2);
            
            % Creating and plotting polygon shape
            pgon = polyshape(X, Y);
            axis(app.UIAxes,'equal')
            plot(app.UIAxes, pgon, 'LineWidth', 2, 'FaceAlpha', 0)
            disp('The polygon has been loaded onto the axes')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadPolygonButton = uibutton(app.UIFigure, 'push');
            app.LoadPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @loadPolygon, true);
            app.LoadPolygonButton.Text = 'Load Polygon';
            app.DrawPolygonButton = uibutton(app.UIFigure, 'push');
            app.DrawPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @drawPolygon, true);
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.SavePolygonButton = uibutton(app.UIFigure, 'push');
            app.SavePolygonButton.ButtonPushedFcn = createCallbackFcn(app, @savePolygon, true);
            app.SavePolygonButton.Text = {'Save Polygon'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end