classdef task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShowAnglesSwitch        matlab.ui.control.Switch
        ShowAnglesSwitchLabel   matlab.ui.control.Label
        PlotColoredDisksButton  matlab.ui.control.Button
        ClearAxesButton         matlab.ui.control.Button
        SavePolygonButton       matlab.ui.control.Button
        DrawPolygonButton       matlab.ui.control.Button
        LoadPolygonButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        roi % Description
        Temp % Description
        X % Description
        Y % Description
        crdnt_angle % Description
        Clockwise_Check
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawPolygonButton
        function drawPolygon(app, event)
            % This code is used from TASK 1.
            % Allows the user to draw a shape on the Axes
            
            app.roi = drawpolygon(app.UIAxes);
        end

        % Button pushed function: SavePolygonButton
        function savePolygon(app, event)
            % This code is used from TASK 1.
            % In order to use save button the user must draw something
            % on the axes before saving.
            % Save button will save all the coordinates of drawn polygon to
            % space separated text file.
            
            coordinates = app.roi.Position;
            name = input ('What would you like to call the file? e.g "Test123" > ', 's');
            dlmwrite([name,'.txt'],coordinates,'delimiter',' ');
        end

        % Button pushed function: LoadPolygonButton
        function loadPolygon(app, event)
            % This code is used from TASK 1.
            cla(app.UIAxes,"reset")
            % The user may only use {.dat and .txt} files to load data from.
            
            [filename, ~] = uigetfile({'*.dat;*.txt'}, 'Pick a file to load data from');
            if filename ~= 0
                % Checks the file extension on selected file
                [~, ~, fExt] = fileparts(filename);
                  switch lower(fExt)
                      case '.txt'
                        app.Temp = importdata(filename, ' ');
                      case '.dat'
                        app.Temp = importdata(filename);
                      otherwise 
                        error('Unexpected file extension: %s', fExt);
                  end
            end
            
            % Gets X and Y coordinates from Temp variable.
            app.X = app.Temp(:,1);
            app.Y = app.Temp(:,2);
            
            % Returns True if polygons vertices are in clockwise order
            app.Clockwise_Check = ispolycw(app.X,app.Y);
            
            % Creating and plotting polygon shape
            pgon = polyshape(app.X, app.Y); 
            axis(app.UIAxes,'equal')
            plot(app.UIAxes,pgon,'LineWidth',2,'FaceAlpha',0) 
            disp('The polygon has been loaded onto the axes')
        end

        % Button pushed function: PlotColoredDisksButton
        function plotColoredDisk(app, event)
            % This code is used for TASK 2.
            % In order to use must load polygon first onto axes
            
            Z = [app.Temp(end,:); app.Temp; app.Temp(1,:)];
            
            % Creating an empty matrix of zeros to avoid the loop from having to copy 
            % the values into a new array each time the loop runs which can
            % increase run time of the program.
            app.crdnt_angle = zeros(1,size(Z,1) - 2);
            
            % Calculates the angles between three given verticies for each
            % vertex in the polygon loaded onto the axes.
            % Code used for finding angles was provided in the labs. (J Burkardt, 2005) 
            for k = 2:size(Z,1)-1
                if app.Clockwise_Check 
                    app.crdnt_angle(:, k-1) = 360 - angles([Z(k-1,1) Z(k-1,2)], ...
                        [Z(k,1) Z(k,2)], [Z(k+1,1) Z(k+1,2)]);
                else     
                    app.crdnt_angle(:,k-1) = angles([Z(k-1,1) Z(k-1,2)], ...
                        [Z(k,1) Z(k,2)], [Z(k+1,1) Z(k+1,2)]);
                end
            end
  
            axis(app.UIAxes,'equal')
            hold(app.UIAxes,"on")
            for ii = 2:size(Z,1)-1
               % Checks if a coordinate is convex or concave based on its angle
               % Plots the disk with a different size based on the angle
                   colorbar(app.UIAxes);
                   colormap(app.UIAxes,'jet'); % Maps the colorbar to the jet color (ROYGBIV) 
                   caxis(app.UIAxes,[0 360]); % Making the colorbar range from [0 360]
               if app.crdnt_angle(ii - 1) >= 180
                   scatter(app.UIAxes,Z(ii,1),Z(ii,2),100,app.crdnt_angle(ii -1),'filled');
               elseif app.crdnt_angle(ii - 1) < 180
                   scatter(app.UIAxes,Z(ii,1),Z(ii,2),50,app.crdnt_angle(ii -1),'filled');
               end
            end
            hold(app.UIAxes,"off")
        end

        % Value changed function: ShowAnglesSwitch
        function ShowAnglesSwitchValueChanged(app, event)
            % Optional Code for TASK 2.
            value = app.ShowAnglesSwitch.Value;
            
            if strcmp(value,'On')
                text(app.UIAxes,app.X, app.Y, compose('\v\v\t%.1f°',app.crdnt_angle));
            else
                delete(findobj(app.UIAxes,'type','text'));
            end     
        end

        % Button pushed function: ClearAxesButton
        function clearAxes(app, event)
            % Fully resets the Axes
            cla(app.UIAxes,'reset')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadPolygonButton = uibutton(app.UIFigure, 'push');
            app.LoadPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @loadPolygon, true);
            app.LoadPolygonButton.Text = {'Load Polygon'; ''};
            app.DrawPolygonButton = uibutton(app.UIFigure, 'push');
            app.DrawPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @drawPolygon, true);
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.SavePolygonButton = uibutton(app.UIFigure, 'push');
            app.SavePolygonButton.ButtonPushedFcn = createCallbackFcn(app, @savePolygon, true);
            app.SavePolygonButton.Text = 'Save Polygon';
            app.ClearAxesButton = uibutton(app.UIFigure, 'push');
            app.ClearAxesButton.ButtonPushedFcn = createCallbackFcn(app, @clearAxes, true);
            app.ClearAxesButton.Text = 'Clear Axes';
            app.PlotColoredDisksButton = uibutton(app.UIFigure, 'push');
            app.PlotColoredDisksButton.ButtonPushedFcn = createCallbackFcn(app, @plotColoredDisk, true);
            app.PlotColoredDisksButton.Text = 'Plot Colored Disks';
            app.ShowAnglesSwitchLabel = uilabel(app.UIFigure);
            app.ShowAnglesSwitchLabel.Text = 'Show Angles';
            app.ShowAnglesSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowAnglesSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowAnglesSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end