classdef task3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShowAnglesSwitch        matlab.ui.control.Switch
        ShowAnglesSwitchLabel   matlab.ui.control.Label
        PlotColoredDisksButton  matlab.ui.control.Button
        PlotCirclesButton       matlab.ui.control.Button
        DrawMBRButton           matlab.ui.control.Button
        ClearAxesButton         matlab.ui.control.Button
        SavePolygonButton       matlab.ui.control.Button
        DrawPolygonButton       matlab.ui.control.Button
        LoadPolygonButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        roi % Description
        pgon % Description
        x_centerPoint % Description
        y_centerPoint % Description
        xlim % Description
        ylim % Description
        X % Description
        Y % Description
        Z % Description
        Temp % Description
        crdnt_angle % Description
        Clockwise_Check % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawPolygonButton
        function drawPolygon(app, event)
            % This code is used from TASK 1.
            % Allows the user to draw a shape on the Axes
            
            app.roi = drawpolygon(app.UIAxes);
        end

        % Button pushed function: SavePolygonButton
        function savePolygon(app, event)
            % This code is used from TASK 1.
            % In order to use save button the user must draw something
            % on the axes before saving.
            % Save button will save all the coordinates of drawn polygon to
            % space separated file.
            
            coordinates = app.roi.Position;
            name = input ('What would you like to call the file? e.g "Test123" > ', 's');
            dlmwrite([name,'.txt'],coordinates,'delimiter',' ');
        end

        % Button pushed function: LoadPolygonButton
        function loadPolygon(app, event)
            % This code is used from TASK 1.
            cla(app.UIAxes,'reset')
            % The user may only use {.dat and .txt} files to load data from.
            
            [filename, ~] = uigetfile({'*.dat;*.txt'}, 'Pick a txt file to load data from');
            if filename~=0
                % Checks the file extension on selected file
                [~, ~, fExt] = fileparts(filename);
                  switch lower(fExt)
                      case '.txt'
                        app.Temp = importdata(filename, ' ');
                      case '.dat'
                        app.Temp = importdata(filename);
                      otherwise 
                        error('Unexpected file extension: %s', fExt);
                  end
            end
            % Gets X and Y coordinates from Temp variable.
            app.X = app.Temp(:,1);
            app.Y = app.Temp(:,2);
            
            % Returns True if polygons vertices are in clockwise order
            app.Clockwise_Check = ispolycw(app.X,app.Y);
            
            % Creating and plotting polygon shape
            app.pgon = polyshape(app.X, app.Y);
            axis(app.UIAxes,'equal')
            plot(app.UIAxes,app.pgon,'LineWidth',2,'FaceAlpha',0)
            disp('The polygon has been loaded onto the axes')
        end

        % Button pushed function: PlotColoredDisksButton
        function plotColoredDisks(app, event)
            % This code is used from TASK 2.
            % In order to use must load polygon first onto axes
            
            app.Z = [app.Temp(end,:); app.Temp; app.Temp(1,:)];
            
            % Creating an empty matrix of zeros to avoid the loop from having to copy 
            % the values into a new array each time the loop runs which can
            % increase run time of the program.
            app.crdnt_angle = zeros(1,size(app.Z,1) - 2);
            
            % Calculates the angles between three given verticies for each
            % vertex in the polygon loaded onto the axes.
            % Code used for finding angles was provided in the labs. (J Burkardt, 2005)
            for k = 2:size(app.Z,1)-1
                if app.Clockwise_Check 
                    app.crdnt_angle(:, k-1) = 360 - angles([app.Z(k-1,1) app.Z(k-1,2)], ...
                        [app.Z(k,1) app.Z(k,2)], [app.Z(k+1,1) app.Z(k+1,2)]);
                else     
                    app.crdnt_angle(:,k-1) = angles([app.Z(k-1,1) app.Z(k-1,2)], ...
                        [app.Z(k,1) app.Z(k,2)], [app.Z(k+1,1) app.Z(k+1,2)]);
                end
            end
  
            axis(app.UIAxes,'equal')
            hold(app.UIAxes,"on")
            for ii = 2:size(app.Z,1)-1
               % Checks if a coordinate is convex or concave based on its angle
               % Plots the disk with a different size based on the angle
               colorbar(app.UIAxes);  
               colormap(app.UIAxes,'jet'); % Maps the colorbar to the jet color (ROYGBIV) 
               caxis(app.UIAxes,[0 360]); % Making the colorbar range from [0 360]
               if app.crdnt_angle(ii - 1) >= 180
                   scatter(app.UIAxes,app.Z(ii,1),app.Z(ii,2),100,app.crdnt_angle(ii -1),'filled'); 
               elseif app.crdnt_angle(ii - 1) < 180
                   scatter(app.UIAxes,app.Z(ii,1),app.Z(ii,2),50,app.crdnt_angle(ii -1),'filled'); 
               end
            end
            hold(app.UIAxes,"off")
        end

        % Button pushed function: DrawMBRButton
        function drawMBR(app, event)
            % Code used for TASK 3.
            % In order to use must load polygon first onto axes
           
            % Gets points of the Minimum Bounding Rectangle (MBR)
            [app.xlim,app.ylim] = boundingbox(app.pgon);
            
            % Gets center of the MBR
            % midpoint = (x1 + x2) / 2, (y1 + y2) / 2
            app.x_centerPoint = (app.xlim(1) + app.xlim(2)) / 2; 
            app.y_centerPoint = (app.ylim(1) + app.ylim(2)) / 2;
            
            % Plots MBR and center point of MBR
            hold(app.UIAxes,"on")
            axis(app.UIAxes,'equal')
            rectangle(app.UIAxes,'Position',[app.xlim(1) app.ylim(1) (app.xlim(2) ...
                - app.xlim(1)) (app.ylim(2) - app.ylim(1))], 'EdgeColor','c')
            plot(app.UIAxes, app.x_centerPoint, app.y_centerPoint, 'ro')
        end

        % Button pushed function: PlotCirclesButton
        function plotCircles(app, event)
            % Code used for TASK 3.
           
            % In order to use must load a polygon first, then calculate MBR
            % then use plot circle button.
            % Function plots circles based on the MBR width and height
            
            % Calculates the radius of the circle based on the MBR height
            % and width
            r1=(app.xlim(2) - app.xlim(1)) / 2; 
            r2=(app.ylim(2) - app.ylim(1)) / 2; 
            
            % Plots two circles given the radius of the circle and the
            % center point.
            % Function used here was code provided from labs. (J. Burkardt, 2005)
            circ1 = circle_imp_points_2d(r1, [app.x_centerPoint app.y_centerPoint], 10000);
            circ2 = circle_imp_points_2d(r2, [app.x_centerPoint app.y_centerPoint], 10000);
            
            
            % Plotting the two circles one green, one red
            axis(app.UIAxes,'equal')
            plot(app.UIAxes, circ1(1,:), circ1(2,:),'g',"LineWidth",1)
            plot(app.UIAxes, circ2(1,:), circ2(2,:),'r',"LineWidth",1)
            
            % Finding the intersections of the polygon and the circles
            V=app.pgon.Vertices;
            N=size(V,1);
            V=V([1:N,1],:);
            
            hold(app.UIAxes,"on")
            
            % Center point of MBR
            center = [app.x_centerPoint;app.y_centerPoint]; 
           
            for i=1:N
                x0 = V(i,1);
                y0 = V(i,2);
                f = V(i+1,1) - x0;
                g = V(i+1,2) - y0;
                
                % distance between vertices.
                distance1 = sqrt((V(i+1,1) - V(i,1))^2 + (V(i+1,2) - V(i,2))^2);
                
                % Calculating discriminant to determine if there is an
                % intersetion between polygons sides and circle.
                discriminant1 = (r1^2 * (f^2 + g^2) - (f*(center(2) - y0) - g * (center(1) - x0))^2);
                discriminant2 = (r2^2 * (f^2 + g^2) - (f*(center(2) - y0) - g * (center(1) - x0))^2);
                
                % Check for green circle
                if discriminant1 > 0 || discriminant1 == 0
                    [ num_int, p ] = circle_imp_line_par_int_2d ( r1, center, x0, y0, f, g );
                    if num_int == 2
                        % Checking if the point lies on the line then
                        % plotting it
                        valid_point = floor(sqrt((V(i,1) - p(1,1))^2 + (V(i,2) - p(2,1))^2) + ...
                            sqrt((V(i+1,1) - p(1,1))^2 + (V(i+1,2) - p(2,1))^2)) == floor(distance1);
                        if valid_point
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,1),p(2,1),'*k','MarkerSize',10)
                        end
                        % Checking if the point lies on the line then
                        % plotting it
                        valid_point2 = floor(sqrt((V(i,1) - p(1,2))^2 + (V(i,2) - p(2,2))^2) + ...
                            sqrt((V(i+1,1) - p(1,2))^2 + (V(i+1,2) - p(2,2))^2)) == floor(distance1);
                        if valid_point2
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,2),p(2,2),'*k','MarkerSize',10)
                        end
                    elseif num_int == 1
                        valid_point = floor(sqrt((V(i,1) - p(1,1))^2 + (V(i,2) - p(2,1))^2) + ...
                            sqrt((V(i+1,1) - p(1,1))^2 + (V(i+1,2) - p(2,1))^2)) == floor(distance1);
                        if valid_point
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,1),p(2,1),'*k','MarkerSize',10)
                        end
                    end
    
                end
                
                % Check for red circle
                if discriminant2 > 0 || discriminant2 == 0
                    [ num_int, p ] = circle_imp_line_par_int_2d ( r2, center, x0, y0, f, g );
                    if num_int == 2
                        % Checking if the point lies on the line then
                        % plotting it
                        valid_point = floor(sqrt((V(i,1) - p(1,1))^2 + (V(i,2) - p(2,1))^2) + ...
                            sqrt((V(i+1,1) - p(1,1))^2 + (V(i+1,2) - p(2,1))^2)) == floor(distance1);
                        if valid_point
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,1),p(2,1),'*k','MarkerSize',10)
                        end
                        % Checking if the point lies on the line then
                        % plotting it
                        valid_point2 = floor(sqrt((V(i,1) - p(1,2))^2 + (V(i,2) - p(2,2))^2) + ...
                            sqrt((V(i+1,1) - p(1,2))^2 + (V(i+1,2) - p(2,2))^2)) == floor(distance1);
                        if valid_point2
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,2),p(2,2),'*k','MarkerSize',10)
                        end
                    elseif num_int == 1
                        valid_point = floor(sqrt((V(i,1) - p(1,1))^2 + (V(i,2) - p(2,1))^2) + ...
                            sqrt((V(i+1,1) - p(1,1))^2 + (V(i+1,2) - p(2,1))^2)) == floor(distance1);
                        if valid_point
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,1),p(2,1),'*k','MarkerSize',10)
                        end
                    end

                end
            end
            
            hold(app.UIAxes,"off")
        end

        % Value changed function: ShowAnglesSwitch
        function ShowAnglesSwitchValueChanged(app, event)
            % Optional Code for TASK 2.
            value = app.ShowAnglesSwitch.Value;
            
            if strcmp(value,'On')
                text(app.UIAxes,app.X, app.Y, compose('\v\v\t%.1f°',app.crdnt_angle));
            else
                delete(findobj(app.UIAxes,'type','text'));
            end     
        end

        % Button pushed function: ClearAxesButton
        function clearAxes(app, event)
            % fully resets the Axes
            cla(app.UIAxes,'reset')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LoadPolygonButton = uibutton(app.UIFigure, 'push');
            app.LoadPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @loadPolygon, true);
            app.LoadPolygonButton.Text = 'Load Polygon';
            app.DrawPolygonButton = uibutton(app.UIFigure, 'push');
            app.DrawPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @drawPolygon, true);
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.SavePolygonButton = uibutton(app.UIFigure, 'push');
            app.SavePolygonButton.ButtonPushedFcn = createCallbackFcn(app, @savePolygon, true);
            app.SavePolygonButton.Text = 'Save Polygon';
            app.ClearAxesButton = uibutton(app.UIFigure, 'push');
            app.ClearAxesButton.ButtonPushedFcn = createCallbackFcn(app, @clearAxes, true);
            app.ClearAxesButton.Text = 'Clear Axes';
            app.DrawMBRButton = uibutton(app.UIFigure, 'push');
            app.DrawMBRButton.ButtonPushedFcn = createCallbackFcn(app, @drawMBR, true);
            app.DrawMBRButton.Text = 'Draw MBR';
            app.PlotCirclesButton = uibutton(app.UIFigure, 'push');
            app.PlotCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @plotCircles, true);
            app.PlotCirclesButton.Text = 'Plot Circles';
            app.PlotColoredDisksButton = uibutton(app.UIFigure, 'push');
            app.PlotColoredDisksButton.ButtonPushedFcn = createCallbackFcn(app, @plotColoredDisks, true);
            app.PlotColoredDisksButton.Text = 'Plot Colored Disks';
            app.ShowAnglesSwitchLabel = uilabel(app.UIFigure);
            app.ShowAnglesSwitchLabel.Text = 'Show Angles';
            app.ShowAnglesSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowAnglesSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowAnglesSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end