classdef task6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SaveIMGButton           matlab.ui.control.Button
        NoveltyFunction2Button  matlab.ui.control.Button
        NoveltyFunction1Button  matlab.ui.control.Button
        ShowAnglesSwitch        matlab.ui.control.Switch
        ShowAnglesSwitchLabel   matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        PlotColoredDisksButton  matlab.ui.control.Button
        PlotCirclesButton       matlab.ui.control.Button
        DrawMBRButton           matlab.ui.control.Button
        ClearAxesButton         matlab.ui.control.Button
        SavePolygonButton       matlab.ui.control.Button
        DrawPolygonButton       matlab.ui.control.Button
        LoadPolygonButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        roi % Description
        pgon % Description
        x_centerPoint % Description
        y_centerPoint % Description
        xlim % Description
        ylim % Description
        X % Description
        Y % Description
        Z % Description
        Temp % Description
        crdnt_angle % Description
        V % Description
        N % Description
        xy % Description
        xy2 % Description
        polygonObj % Description
        fExt
        Clockwise_Check
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawPolygonButton
        function drawPolygon(app, event)
            % This code is used from TASK 1.
            % Allows the user to draw a shape on the Axes
            
            app.roi = drawpolygon(app.UIAxes);
        end

        % Button pushed function: SavePolygonButton
        function savePolygon(app, event)
            % This code is used from TASK 1.
            % In order to use save button the user must draw something
            % on the axes before saving.
            % Save button will save all the coordinates of drawn polygon to
            % space separated file.
            
            coordinates = app.roi.Position;
            name = input ('What would you like to call the file? e.g "Test123" > ', 's');
            dlmwrite([name,'.txt'], coordinates, 'delimiter', ' ');
        end

        % Button pushed function: LoadPolygonButton
        function loadPolygon(app, event)
            % This code is used from TASK 1.
            cla(app.UIAxes,'reset')
            % The user may only use {.dat and .txt} files to load data from.
            
            [filename, ~] = uigetfile({'*.dat;*.txt'}, 'Pick a file to load data from');
            if filename ~= 0
                % Checks the file extension on selected file
                [~, ~, app.fExt] = fileparts(filename);
                  switch lower(app.fExt)
                      case '.txt'
                        app.Temp = importdata(filename, ' ');
                      case '.dat'
                        app.Temp = importdata(filename);
                      otherwise 
                        error('Unexpected file extension: %s', app.fExt);
                  end
            else
                disp('Error: No file was selected!')
                return;
            end
            
            % Gets X and Y coordinates from Temp variable.
            app.X = app.Temp(:,1);
            app.Y = app.Temp(:,2);
            
            % Returns True if polygons vertices were loaded in clockwise order
            app.Clockwise_Check = ispolycw(app.X,app.Y);
            
            % Creating and plotting polygon shape
            app.pgon = polyshape(app.X, app.Y);
            axis(app.UIAxes,'equal')
            app.polygonObj = plot(app.UIAxes,app.pgon,'LineWidth',2,'FaceAlpha',0); % PolyObject
            disp('The polygon has been loaded onto the axes')
            
            % HoverOverShape function is run when the file is loaded
            %hoverOverShape(app,event) % Required for hover function to work
        end

        % Button pushed function: PlotColoredDisksButton
        function plotColoredDisks(app, event)
            % This code is used from TASK 2.
            % In order to use must load polygon first onto axes
            
            app.Z = [app.Temp(end,:); app.Temp; app.Temp(1,:)];
            
            % Creating an empty matrix of zeros to avoid the loop from having to copy 
            % the values into a new array each time the loop runs which can
            % increase run time of the program.
            app.crdnt_angle = zeros(1,size(app.Z,1) - 2);
            
            % Calculates the angles between three given verticies for each
            % vertex in the polygon loaded onto the axes.
            % Code used for finding angles was provided in the labs. 
            for k = 2:size(app.Z,1)-1
                if app.Clockwise_Check 
                    app.crdnt_angle(:, k-1) = 360 - angles([app.Z(k-1,1) app.Z(k-1,2)], [app.Z(k,1) app.Z(k,2)], [app.Z(k+1,1) app.Z(k+1,2)]);
                else     
                    app.crdnt_angle(:,k-1) = angles([app.Z(k-1,1) app.Z(k-1,2)], [app.Z(k,1) app.Z(k,2)], [app.Z(k+1,1) app.Z(k+1,2)]);
                end
            end
  
            axis(app.UIAxes,'equal')
            hold(app.UIAxes,"on")
            for ii = 2:size(app.Z,1)-1
               % Checks if a coordinate is convex or concave based on its angle
               % Plots the disk with a different size based on the angle
               if app.crdnt_angle(ii - 1) >= 180 
                   scatter(app.UIAxes,app.Z(ii,1),app.Z(ii,2),100,app.crdnt_angle(ii -1),'filled');
                   colorbar(app.UIAxes);  
                   colormap(app.UIAxes,'jet'); % Maps the colorbar to the jet color (ROYGBIV) 
                   caxis(app.UIAxes,[0 360]); % Making the colorbar range from [0 360]
               elseif app.crdnt_angle(ii - 1) < 180
                   scatter(app.UIAxes,app.Z(ii,1),app.Z(ii,2),50,app.crdnt_angle(ii -1),'filled'); 
                   colorbar(app.UIAxes);
                   colormap(app.UIAxes,'jet');
                   caxis(app.UIAxes,[0 360]);
               end
            end
            hold(app.UIAxes,"off")
        end

        % Button pushed function: DrawMBRButton
        function drawMBR(app, event)
            % This code is used from TASK 3.
            % In order to use must load polygon first onto axes
            
            % Gets points of the Minimum Bounding Rectangle (MBR)
            [app.xlim,app.ylim] = boundingbox(app.pgon);
            
            % Gets center of the MBR
            % midpoint = (x1 + x2) / 2, (y1 + y2) / 2
            app.x_centerPoint = (app.xlim(1) + app.xlim(2)) / 2;
            app.y_centerPoint = (app.ylim(1) + app.ylim(2)) / 2;
            
            % Plots MBR and center point of MBR
            hold(app.UIAxes,"on")
            axis(app.UIAxes,'equal')
            rectangle(app.UIAxes,'Position',[app.xlim(1) app.ylim(1) (app.xlim(2) - app.xlim(1)) (app.ylim(2) - app.ylim(1))], 'EdgeColor','c', "LineWidth", 1)  
            plot(app.UIAxes, app.x_centerPoint, app.y_centerPoint, 'ro')
        end

        % Button pushed function: PlotCirclesButton
        function plotCircles(app, event)
            % This code is used from TASK 3 and 4.
                        
            % In order to use must load a polygon first, then calculate MBR
            % then use plot circle button.
            % Function plots circles based on the MBR width and height as
            % well as line segment
            
            % Calculates the radius of the circle based on the MBR height
            % and width
            r1=(app.xlim(2) - app.xlim(1)) / 2; 
            r2=(app.ylim(2) - app.ylim(1)) / 2; 
            
            
            % Plots two circles given the radius of the circle and the
            % center point.
            % Function used here was code provided from labs. (J. Burkardt, 2005)
            circ1 = circle_imp_points_2d(r1, [app.x_centerPoint app.y_centerPoint], 10000);
            circ2 = circle_imp_points_2d(r2, [app.x_centerPoint app.y_centerPoint], 10000);
            
            
            % Plotting the two circles based on their radiuses
            %axis(app.UIAxes,'equal')
            plot(app.UIAxes, circ1(1,:), circ1(2,:),'g',"LineWidth",1)
            plot(app.UIAxes, circ2(1,:), circ2(2,:),'r',"LineWidth",1)
            
            
            % Finding the intersections of the polygon and the circles
            app.V=app.pgon.Vertices;
            app.N=size(app.V,1);
            app.V=app.V([1:app.N,1],:);
            
            % Required for linexlines2D function to work for line segments
            circle1 = nsidedpoly(10000, 'Center', [app.x_centerPoint app.y_centerPoint], 'Radius', r1);
            circle2 = nsidedpoly(10000, 'Center', [app.x_centerPoint app.y_centerPoint], 'Radius', r2);
            
            hold(app.UIAxes,"on")
            center = [app.x_centerPoint;app.y_centerPoint];
            for i=1:app.N
                x0 = app.V(i,1);
                y0 = app.V(i,2);
                f = app.V(i+1,1) - x0;
                g = app.V(i+1,2) - y0;
                
                % distance between points
                distance1 = sqrt((app.V(i+1,1) - app.V(i,1))^2 + (app.V(i+1,2) - app.V(i,2))^2);
                
                % Calculating discriminant to determine if there is an
                % intersetion
                discriminant1 = (r1^2 * (f^2 + g^2) - (f*(center(2) - y0) - g * (center(1) - x0))^2);
                discriminant2 = (r2^2 * (f^2 + g^2) - (f*(center(2) - y0) - g * (center(1) - x0))^2);
                
                % Check for green circle
                if discriminant1 > 0 || discriminant1 == 0
                    [ num_int, p ] = circle_imp_line_par_int_2d ( r1, center, x0, y0, f, g );
                    if num_int == 2
                        % Checking if the point lies on the line then
                        % plotting it
                        valid_point = floor(sqrt((app.V(i,1) - p(1,1))^2 + (app.V(i,2) - p(2,1))^2) + ...
                            sqrt((app.V(i+1,1) - p(1,1))^2 + (app.V(i+1,2) - p(2,1))^2)) == floor(distance1);
                        if valid_point
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,1),p(2,1),'*k','MarkerSize',10)
                        end
                        % Checking if the point lies on the line then
                        % plotting it
                        valid_point2 = floor(sqrt((app.V(i,1) - p(1,2))^2 + (app.V(i,2) - p(2,2))^2) + ...
                            sqrt((app.V(i+1,1) - p(1,2))^2 + (app.V(i+1,2) - p(2,2))^2)) == floor(distance1);
                        if valid_point2
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,2),p(2,2),'*k','MarkerSize',10)
                        end
                    elseif num_int == 1
                        valid_point = floor(sqrt((app.V(i,1) - p(1,1))^2 + (app.V(i,2) - p(2,1))^2) + ...
                            sqrt((app.V(i+1,1) - p(1,1))^2 + (app.V(i+1,2) - p(2,1))^2)) == floor(distance1);
                        if valid_point
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,1),p(2,1),'*k','MarkerSize',10)
                        end
                    end
    
                end
                
                % Check for red circle
                if discriminant2 > 0 || discriminant2 == 0
                    [ num_int, p ] = circle_imp_line_par_int_2d ( r2, center, x0, y0, f, g );
                    if num_int == 2
                        % Checking if the point lies on the line then
                        % plotting it
                        valid_point = floor(sqrt((app.V(i,1) - p(1,1))^2 + (app.V(i,2) - p(2,1))^2) + ...
                            sqrt((app.V(i+1,1) - p(1,1))^2 + (app.V(i+1,2) - p(2,1))^2)) == floor(distance1);
                        if valid_point
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,1),p(2,1),'*k','MarkerSize',10)
                        end
                        % Checking if the point lies on the line then
                        % plotting it
                        valid_point2 = floor(sqrt((app.V(i,1) - p(1,2))^2 + (app.V(i,2) - p(2,2))^2) + ...
                            sqrt((app.V(i+1,1) - p(1,2))^2 + (app.V(i+1,2) - p(2,2))^2)) == floor(distance1);
                        if valid_point2
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,2),p(2,2),'*k','MarkerSize',10)
                        end
                    elseif num_int == 1
                        valid_point = floor(sqrt((app.V(i,1) - p(1,1))^2 + (app.V(i,2) - p(2,1))^2) + ...
                            sqrt((app.V(i+1,1) - p(1,1))^2 + (app.V(i+1,2) - p(2,1))^2)) == floor(distance1);
                        if valid_point
                            % Plotting * at the circle and polygon intersects
                            plot(app.UIAxes,p(1,1),p(2,1),'*k','MarkerSize',10)
                        end
                    end

                end


  %TASK 4       % Code used for TASK 4
  
                %% NOTE: Function linexlines2D() is a third party library
                % tool for computing the intersection points between a reference 2D line segment 
                % and a batch of other 2D line segments.
                % Reference to the library can be found below this line.
                % J, M., 2021. Intersections of multiple 2D lines or line segments. [online] Mathworks. Available at: <https://www.mathworks.com/matlabcentral/fileexchange/93470-intersections-of-multiple-2d-lines-or-line-segments> [Accessed 25 November 2021].
                % This library was used to find the points of the line segments.
                
                % NOTE: Section of code used for task 4 can be found in the
                % link below.
                % MathWorks. 2021. How can I rotate a set of points in a plane by a certain angle about an arbitrary point?. 
                % [online] Available at: 
                % <https://uk.mathworks.com/matlabcentral/answers/93554-how-can-i-rotate-a-set-of-points-in-a-plane-by-a-certain-angle-about-an-arbitrary-point> [Accessed 26 November 2021].
                
                % Function linexline2d was used to find the points of the line segemnt 
                app.xy=linexlines2D(circle1, app.V(i,:), app.V(i+1,:));
                app.xy2=linexlines2D(circle2, app.V(i,:), app.V(i+1,:));
                
                % Checks if a line segment has a empty double vector and
                % sets them to NaN
                if (isempty(app.xy2(1,:)) || isempty(app.xy(1,:)))
                    [xy1x, xy1y, xy2x, xy2y] = deal(NaN);
                else
                    xy1x = app.xy(1,1);
                    xy1y = app.xy(2,1);
                    xy2x = app.xy2(1,1);
                    xy2y = app.xy2(2,1);
                end
                
                % Plots the line segment at zero degrees.
                plot(app.UIAxes,[xy1x xy2x], [xy1y xy2y],'m','LineWidth',2)
                
                
                % NOTE sections of code below was adapted from link given
                % above. (How can I rotate a set of points in a plane by a certain angle about an arbitrary point?, 2021)
                
                % Calculating the new points of the line segment after
                % every three degrees based on the center point of the circles.
                matrix_of_points = [[xy1x xy2x];[xy1y xy2y]];      
                center = repmat([app.x_centerPoint; app.y_centerPoint], 1, length(matrix_of_points(1,:)));
                
                for angles = 3:3:15
                    theta = (-angles * pi) / 180;  % Rad to Deg = (deg * pi) / 180
                    R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
                
                    s = matrix_of_points - center;
                    so = R*s;
                    vo = so + center;
                
                    x_rotated = vo(1,:);
                    y_rotated = vo(2,:);
                    
                    % Plots line segment every three degrees.
                    plot(app.UIAxes,matrix_of_points(1,:), matrix_of_points(2,:), 'k-', x_rotated, y_rotated, 'm-');
                    plot(app.UIAxes,matrix_of_points(1,:), matrix_of_points(2,:), 'k-', x_rotated, y_rotated, 'm*');
                end
            end
            
            hold(app.UIAxes,"off")
        end

        % Button pushed function: Button
        function hoverOverShape(app, event)
            % This code is used from TASK 5.
            % Highlights the side of a shape in the mouse hover over it
            
            % Create a line and leaves it invisible
            EdgeObj = line(app.UIAxes, NaN, NaN, 'LineWidth', 4, 'Color', 'red');
            EdgeObj.Visible = false;
            
            % Callback when the mouse moves.
            app.UIFigure.WindowButtonMotionFcn = @(~, ~) mouseonhover(app.polygonObj, EdgeObj);
            
            function mouseonhover(polygonObj, EdgeObj)
                % Finds out current position of the mouse.
                xPos_mouse = polygonObj.Parent.CurrentPoint(1,1);
                yPos_mouse = polygonObj.Parent.CurrentPoint(1,2);
                
                % Checks if mouse pointer is within the polygon.
                if isinterior(app.pgon, xPos_mouse, yPos_mouse)
                    vertices = [app.pgon.Vertices; app.pgon.Vertices(1,:)];
                    
                    % Highlights a line red if the mouse pointers is close to one of its edges.
                    EdgeObj.XData = vertices(highlightedge([xPos_mouse yPos_mouse],... 
                        vertices)+[0 1], 1);
                    EdgeObj.YData = vertices(highlightedge([xPos_mouse yPos_mouse], ...
                        vertices)+[0 1], 2);
                    EdgeObj.Visible = true;
                else
                    % Stop highlighting edge when mouse is far from an edge
                    EdgeObj.Visible = false;
                end
            end
        end

        % Button pushed function: NoveltyFunction1Button
        function noveltyFunction1(app, event)
            % This code is used for TASK 6.
            % Novelty Function 1
            
            % Calculating the centerpoint of the polygon
            [xboundry,yboundry] = boundingbox(app.pgon);
            PolyX_centerPoint = (xboundry(1) + xboundry(2)) / 2; 
            PolyY_centerPoint = (yboundry(1) + yboundry(2)) / 2;
            
            axis(app.UIAxes,"equal")
            hold(app.UIAxes,"on")
            % Rotates the shape 360 degrees about its centerpoint.
            for ii = 0:10:360
                poly1 = rotate(app.pgon,ii,[PolyX_centerPoint PolyY_centerPoint]);
                plot(app.UIAxes,poly1);
                pause(0.25);
                cla(app.UIAxes)
            end
            plot(app.UIAxes,app.pgon)
            
            % Traces the path the of the first rotation and plot the new
            % position of the polygon every 10 degrees in different colors.
            for jj = 0:10:360
                poly2 = rotate(app.pgon,jj,[PolyX_centerPoint PolyY_centerPoint]);
                plot(app.UIAxes,poly2);
                pause(0.25);
            end
            
            hold(app.UIAxes,"off")
        end

        % Button pushed function: NoveltyFunction2Button
        function noveltyFunction2(app, event)
            % This code is used for Task 6
            % Novelty Function 2
            
            %NOTE axes need to be cleared and shape needs to be reloaded on
            %to axes for novelty function 2 to work.
            
            % Plotting and making the 2D plot into a 3D plot by adding thickness.
            A = transpose(app.X);
            A = [A A(1)];
            B = transpose(app.Y);
            B = [B B(1)];
            s = surf(app.UIAxes,A, B, ones(numel(A),1)*B);
            axis(app.UIAxes,"vis3d")
            pause_val = 0.25;
            angle_val = 20;
            
            % Plays a sound as the function runs.
            function play(speed, volume)               
                %This audioread function below reads the audio
                [y, Fs] = audioread('novelfunction2.wav');
                sound(y * volume, Fs * speed)
            end
            play(1,0.5)
            
            % Changes the color of the shape as it rotates.
            colorbar(app.UIAxes)
            for i = 1:8
                direction = [1 0 0];
                rotate(s,direction,angle_val)
                colormap(app.UIAxes,'parula')
                pause(pause_val)
                colormap(app.UIAxes,'jet')
                direction = [1 0 0];
                rotate(s,direction,angle_val)
                pause(pause_val)
                colormap(app.UIAxes,'hsv')
                direction = [0 1 0];
                rotate(s,direction,angle_val)
                pause(pause_val)
                colormap(app.UIAxes,'cool')
                direction = [0 1 0];
                rotate(s,direction,angle_val)
                pause(pause_val)
                colormap(app.UIAxes,'spring')
                direction = [0 0 1];
                rotate(s,direction,angle_val)
                pause(pause_val)
                colormap(app.UIAxes,'summer')
                pause(pause_val)
                direction = [0 0 1];
                rotate(s,direction,angle_val)
                colormap(app.UIAxes,'autumn')
                pause(pause_val)
                colormap(app.UIAxes,'winter')
                direction = [1 0 0];
                rotate(s,direction,angle_val)
            end
        end

        % Value changed function: ShowAnglesSwitch
        function ShowAnglesSwitchValueChanged(app, event)
            % Optional code for TASK 2.
            value = app.ShowAnglesSwitch.Value;
            
            if strcmp(value,'On')
                text(app.UIAxes,app.X, app.Y, compose('\v\v\t%.1f°',app.crdnt_angle));
            else
                delete(findobj(app.UIAxes,'type','text'));
            end     
        end

        % Button pushed function: SaveIMGButton
        function saveImage(app, event)
            % Optional feature to save the axes plot as an image
            F = getframe(app.UIAxes);
            Image = frame2im(F);
            name = input ('Name the image > ', 's');
            imwrite(Image, [name '.jpg'])
        end

        % Button pushed function: ClearAxesButton
        function clearAxes(app, event)
            % Fully resets the axes
            cla(app.UIAxes,'reset')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LoadPolygonButton = uibutton(app.UIFigure, 'push');
            app.LoadPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @loadPolygon, true);
            app.LoadPolygonButton.Text = 'Load Polygon';
            app.DrawPolygonButton = uibutton(app.UIFigure, 'push');
            app.DrawPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @drawPolygon, true);
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.SavePolygonButton = uibutton(app.UIFigure, 'push');
            app.SavePolygonButton.ButtonPushedFcn = createCallbackFcn(app, @savePolygon, true);
            app.SavePolygonButton.Text = 'Save Polygon';
            app.ClearAxesButton = uibutton(app.UIFigure, 'push');
            app.ClearAxesButton.ButtonPushedFcn = createCallbackFcn(app, @clearAxes, true);
            app.ClearAxesButton.Text = 'Clear Axes';
            app.DrawMBRButton = uibutton(app.UIFigure, 'push');
            app.DrawMBRButton.ButtonPushedFcn = createCallbackFcn(app, @drawMBR, true);
            app.DrawMBRButton.Text = 'Draw MBR';
            app.PlotCirclesButton = uibutton(app.UIFigure, 'push');
            app.PlotCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @plotCircles, true);
            app.PlotCirclesButton.Text = 'Plot Circles';
            app.PlotColoredDisksButton = uibutton(app.UIFigure, 'push');
            app.PlotColoredDisksButton.ButtonPushedFcn = createCallbackFcn(app, @plotColoredDisks, true);
            app.PlotColoredDisksButton.Text = 'Plot Colored Disks';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @hoverOverShape, true);
            app.Button.Text = '';
            app.ShowAnglesSwitchLabel = uilabel(app.UIFigure);
            app.ShowAnglesSwitchLabel.Text = 'Show Angles';
            app.ShowAnglesSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowAnglesSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowAnglesSwitchValueChanged, true);
            app.NoveltyFunction1Button = uibutton(app.UIFigure, 'push');
            app.NoveltyFunction1Button.ButtonPushedFcn = createCallbackFcn(app, @noveltyFunction1, true);
            app.NoveltyFunction1Button.Text = 'Novelty Function 1';
            app.NoveltyFunction2Button = uibutton(app.UIFigure, 'push');
            app.NoveltyFunction2Button.ButtonPushedFcn = createCallbackFcn(app, @noveltyFunction2, true);
            app.NoveltyFunction2Button.Text = 'Novelty Function 2';
            app.SaveIMGButton = uibutton(app.UIFigure, 'push');
            app.SaveIMGButton.ButtonPushedFcn = createCallbackFcn(app, @saveImage, true);
            app.SaveIMGButton.Text = {'Save IMG'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end